classdef CovidApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Covid19VisualizationUIFigure  matlab.ui.Figure
        StateorRegionListBox          matlab.ui.control.ListBox
        StateorRegionListBoxLabel     matlab.ui.control.Label
        CountriesListBox              matlab.ui.control.ListBox
        CountriesListBoxLabel         matlab.ui.control.Label
        AveragedofdaysSlider          matlab.ui.control.Slider
        AveragedofdaysSliderLabel     matlab.ui.control.Label
        OptionButtonGroup             matlab.ui.container.ButtonGroup
        DailyButton                   matlab.ui.control.RadioButton
        CumulativeButton              matlab.ui.control.RadioButton
        DatatoPlotButtonGroup         matlab.ui.container.ButtonGroup
        BothButton                    matlab.ui.control.RadioButton
        DeathsButton                  matlab.ui.control.RadioButton
        CasesButton                   matlab.ui.control.RadioButton
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        everything      % Data
        ShowCases       % Cases
        ShowDeaths      % Deaths
        DateMonth       % xaxis labels converted 
        ShowBoth        % Both
        ShowCumul       % Cumulative Data
        ShowDaily       % Daily Data
        DailyDataCases  % Daily Cases Calculated 
        DailyDataDeaths % Daily Deaths Calculated
        AVeragewindow   % Size of avg window
        index           % Index value
        index2          % Second Index
        Name            % Name for title
    end
    
    methods (Access = private)
        function plotData(app)
            cla(app.UIAxes,"reset");
            CumuData = {app.everything{app.index2,3:end}};
                if app.ShowCases
                    z = cellfun(@(v)v(1),CumuData);
                    bar(app.UIAxes,app.DateMonth,movmean(z,[app.AVeragewindow,0]));
                    title(app.UIAxes,['Cumulative Number of Cases in',' ',app.Name])
                end
                if app.ShowDeaths
                    o = cellfun(@(v)v(2),CumuData);
                    plot(app.UIAxes,app.DateMonth,movmean(o,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,['Cumulative Number of Deaths in',' ',app.Name])
                end
                if app.ShowBoth
                    z = cellfun(@(v)v(1),CumuData);
                    bar(app.UIAxes,app.DateMonth,movmean(z,[app.AVeragewindow,0]));
                    hold(app.UIAxes,"on")
                    yyaxis(app.UIAxes,"right")
                    o = cellfun(@(v)v(2),CumuData);
                    plot(app.UIAxes,app.DateMonth,movmean(o,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,['Cumulative Number of Cases and Deaths in',' ',app.Name])
                end
                hold(app.UIAxes,"off")
                xlabel(app.UIAxes,'mm/dd')
                xticks(app.UIAxes,app.DateMonth(1:30:end));
                app.UIAxes.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
                grid(app.UIAxes,'on')
                
        end
        function plotDaily(app)
                cla(app.UIAxes,"reset");
                if app.ShowCases
                    bar(app.UIAxes,app.DateMonth,movmean(app.DailyDataCases,[app.AVeragewindow,0]));
                    title(app.UIAxes,['Daily Number of Cases in',' ',app.Name])
                end
                if app.ShowDeaths
                    plot(app.UIAxes,app.DateMonth,movmean(app.DailyDataDeaths,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,['Daily Number of Deaths in',' ',app.Name])
                end
                if app.ShowBoth
                    bar(app.UIAxes,app.DateMonth,movmean(app.DailyDataCases,[app.AVeragewindow,0]));
                    hold(app.UIAxes,"on")
                    yyaxis(app.UIAxes,"right")
                    plot(app.UIAxes,app.DateMonth,movmean(app.DailyDataDeaths,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,['Daily Number of Cases and Deaths in',' ',app.Name])
                end
            hold(app.UIAxes,"off")
            xlabel(app.UIAxes,'mm/dd')
            xticks(app.UIAxes,app.DateMonth(1:30:end))
            app.UIAxes.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
            grid(app.UIAxes,'on')
        end
        
        function Daily(app)
            CumuData = {app.everything{app.index2,3:end}};
            z = cellfun(@(v)v(1),CumuData);
            o = cellfun(@(v)v(2),CumuData);
            a = zeros(1,375);
            b = zeros(1,375);
            a(1)=z(1);
            b(1)=o(1);
            for i = 2:length(z)
                    a(i)=z(i)-z(i-1);
                    b(i)=o(i)-o(i-1);
                    if a(i)<0
                        a(i) = 0;
                    end
                    if b(i)<0
                        b(i)=0;
                    end
                    
            end
           
             app.DailyDataCases = a;
             app.DailyDataDeaths = b;
 
        end
       
        
        function plotAll(app)
            cla(app.UIAxes,"reset");
            [s,v]=cal();
                if app.ShowCases
                    bar(app.UIAxes,app.DateMonth,movmean(s,[app.AVeragewindow,0]));
                    title(app.UIAxes,'Cumulative Number of Cases Globally')
                end
                if app.ShowDeaths
                    plot(app.UIAxes,app.DateMonth,movmean(v,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,'Cumulative Number of Deaths Globally')
                end
                if app.ShowBoth
                    bar(app.UIAxes,app.DateMonth,movmean(s,[app.AVeragewindow,0]));
                    hold(app.UIAxes,"on")
                    yyaxis(app.UIAxes,"right")
                    plot(app.UIAxes,app.DateMonth,movmean(v,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,'Cumulative Number of Cases and Deaths Globally')
                end
                hold(app.UIAxes,"off")
                xlabel(app.UIAxes,'mm/dd')
                xticks(app.UIAxes,app.DateMonth(1:30:end));
                app.UIAxes.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
                grid(app.UIAxes,'on')
            
            
        end
        
        function plotDA(app)
            cla(app.UIAxes,"reset");
            [s,v]=cal();
            [a,b]=dail(s,v);
                if app.ShowCases
                    bar(app.UIAxes,app.DateMonth,movmean(a,[app.AVeragewindow,0]));
                    title(app.UIAxes,'Daily Number of Cases Globally')
                end
                if app.ShowDeaths
                    plot(app.UIAxes,app.DateMonth,movmean(b,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,'Daily Number of Deaths Globally')
                end
                if app.ShowBoth
                    bar(app.UIAxes,app.DateMonth,movmean(a,[app.AVeragewindow,0]));
                    hold(app.UIAxes,"on")
                    yyaxis(app.UIAxes,"right")
                    plot(app.UIAxes,app.DateMonth,movmean(b,[app.AVeragewindow,0]),"red");
                    title(app.UIAxes,'Daily Number of Cases and Deaths Globally')
                end
                hold(app.UIAxes,"off")
                xlabel(app.UIAxes,'mm/dd')
                xticks(app.UIAxes,app.DateMonth(1:30:end));
                app.UIAxes.XTickLabel = {'01/01'; '02/01'; '03/01'; '04/01'; '05/01'; '06/01'; '07/01'; '08/01'; '09/01'; '10/01'; '11/01'; '12/01'};
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            app.everything = covid_data;
            s = {app.everything{1,3:end}};
            app.DateMonth = datetime(s);
            app.ShowCases = app.CasesButton.Value;
            app.ShowDeaths = app.DeathsButton.Value;
            app.ShowBoth =  app.BothButton.Value;
            app.ShowCumul = app.CumulativeButton.Value;
            app.ShowDaily = app.DailyButton.Value;
            app.AVeragewindow = app.AveragedofdaysSlider.Value; 
            u = unique({app.everything{2:end,1}});
            app.CountriesListBox.Items = {'All',u{1:1:end}}; 
            app.CountriesListBox.Value = 'All';
            app.index=find(contains(app.CountriesListBox.Items,app.CountriesListBox.Value));
            app.StateorRegionListBox.Items = {'All'};
            app.index2 = find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
            app.plotDA();
            app.plotAll()
          
        end

        % Callback function
        function DatatoPlotButtonGroupSelectionChanged(app, event)

            
        end

        % Callback function
        function DatatoPlotButtonGroupButtonDown(app, event)
            
        end

        % Callback function: AveragedofdaysSlider, DatatoPlotButtonGroup, 
        % ...and 2 other components
        function OptionButtonGroupSelectionChanged(app, event)
            if strcmp(app.CountriesListBox.Value,'All')
                app.StateorRegionListBox.Items = {'All'};
                app.AVeragewindow = round(app.AveragedofdaysSlider.Value);
                app.ShowCumul = app.CumulativeButton.Value;
                app.ShowDaily = app.DailyButton.Value;
                if app.ShowCumul
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotAll()
                end
                if app.ShowDaily
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotDA()
        
                end
            else
             app.index=find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
             u = {app.everything{app.index(1):app.index(length(app.index)),2}};
             u{1,1}='All';
             if length(app.index)==1
                    app.StateorRegionListBox.Items = {'All'};
                     app.index2 = find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
                     app.Name = app.CountriesListBox.Value;
             else
                    app.StateorRegionListBox.Items = u;
                    if strcmp(app.StateorRegionListBox.Value,'All')
                        app.index2 = app.index(1);
                        app.Name = app.CountriesListBox.Value;
                    else
                        app.index2 = find(contains(app.everything(1:end,2),app.StateorRegionListBox.Value));
                        app.Name = app.StateorRegionListBox.Value;
                    end
                    
             end
             app.AVeragewindow = round(app.AveragedofdaysSlider.Value);
             app.ShowCumul = app.CumulativeButton.Value;
             app.ShowDaily = app.DailyButton.Value;
             app.Daily()
            if app.ShowCumul
                app.ShowCases = app.CasesButton.Value;
                app.ShowDeaths = app.DeathsButton.Value;
                app.ShowBoth = app.BothButton.Value;
                app.plotData()
            end
            if app.ShowDaily
                app.ShowCases = app.CasesButton.Value;
                app.ShowDeaths = app.DeathsButton.Value;
                app.ShowBoth = app.BothButton.Value;
                app.plotDaily()
        
            end
            end
            
        end

        % Callback function
        function AveragedofdaysSliderValueChanged(app, event)


        end

        % Callback function
        function ListBoxValueChanged(app, event)
        

        end

        % Value changed function: CountriesListBox
        function StateorRegionListBoxValueChanged(app, event)
            if strcmp(app.CountriesListBox.Value,'All')
                app.StateorRegionListBox.Items = {'All'};
                app.AVeragewindow = round(app.AveragedofdaysSlider.Value);
                app.ShowCumul = app.CumulativeButton.Value;
                app.ShowDaily = app.DailyButton.Value;
                if app.ShowCumul
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotAll()
                end
                if app.ShowDaily
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotDA()
        
                end
               
            else
                app.index=find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
                u = {app.everything{app.index(1):app.index(length(app.index)),2}};
                u{1,1}='All';
                if length(app.index)==1
                    app.StateorRegionListBox.Items = {'All'};
                     app.index2 = find(contains(app.everything(1:end,1),app.CountriesListBox.Value));
                     app.Name = app.CountriesListBox.Value;
                else
                    app.StateorRegionListBox.Items = u;
                    if strcmp(app.StateorRegionListBox.Value,'All')
                        app.index2 = app.index(1);
                        app.Name = app.CountriesListBox.Value;
                    else
                        app.index2 = find(contains(app.everything(1:end,2),app.StateorRegionListBox.Value));
                        app.Name = app.StateorRegionListBox.Value;
                    end
                end
                app.AVeragewindow = round(app.AveragedofdaysSlider.Value);
                app.ShowCumul = app.CumulativeButton.Value;
                app.ShowDaily = app.DailyButton.Value;
                app.Daily()
                if app.ShowCumul
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotData()
                end
                if app.ShowDaily
                    app.ShowCases = app.CasesButton.Value;
                    app.ShowDeaths = app.DeathsButton.Value;
                    app.ShowBoth = app.BothButton.Value;
                    app.plotDaily()
        
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Covid19VisualizationUIFigure = uifigure('Visible', 'off');
            app.Covid19VisualizationUIFigure.Name = 'Covid-19 Data Visualization (Data Source: Johns Hopkins University) ';
            app.UIAxes = uiaxes(app.Covid19VisualizationUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'mm/dd')
            ylabel(app.UIAxes, 'count')
            zlabel(app.UIAxes, 'Z')
            app.DatatoPlotButtonGroup = uibuttongroup(app.Covid19VisualizationUIFigure);
            app.DatatoPlotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.DatatoPlotButtonGroup.Title = 'Data to Plot';
            app.CasesButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoPlotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionButtonGroup = uibuttongroup(app.Covid19VisualizationUIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.AveragedofdaysSliderLabel = uilabel(app.Covid19VisualizationUIFigure);
            app.AveragedofdaysSliderLabel.Text = {'Averaged #'; 'of days'};
            app.AveragedofdaysSlider = uislider(app.Covid19VisualizationUIFigure);
            app.AveragedofdaysSlider.Limits = [1 15];
            app.AveragedofdaysSlider.ValueChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.AveragedofdaysSlider.Value = 1;
            app.CountriesListBoxLabel = uilabel(app.Covid19VisualizationUIFigure);
            app.CountriesListBoxLabel.Text = 'Countries:';
            app.CountriesListBox = uilistbox(app.Covid19VisualizationUIFigure);
            app.CountriesListBox.ValueChangedFcn = createCallbackFcn(app, @StateorRegionListBoxValueChanged, true);
            app.CountriesListBox.Value = 'Item 3';
            app.StateorRegionListBoxLabel = uilabel(app.Covid19VisualizationUIFigure);
            app.StateorRegionListBoxLabel.Text = {'State'; 'or Region :'};
            app.StateorRegionListBox = uilistbox(app.Covid19VisualizationUIFigure);
            app.StateorRegionListBox.ValueChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            % Show the figure after all components are created
            app.Covid19VisualizationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Covid19VisualizationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Covid19VisualizationUIFigure)
        end
    end
end